classdef speechDSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        soundButton           matlab.ui.control.Button
        originalsignalButton  matlab.ui.control.Button
        UIAxes_6              matlab.ui.control.UIAxes
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: originalsignalButton
        function originalsignalButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            t = (0:(length(audio)-1))*(1/fs);
            
            audio_fft = fft(audio);

            freqfft = (0:(length(audio_fft)/2-1))*(fs/length(audio_fft));
            
            plot(app.UIAxes,t, audio);

            plot(app.UIAxes_2,freqfft, abs(audio_fft(1:length(audio_fft)/2)));

        end

        % Button pushed function: soundButton
        function soundButtonPushed(app, event)
            filename = 'keyboad-typing.wav';
            [audio, fs] = audioread(filename); 
            sound(audio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.originalsignalButton = uibutton(app.UIFigure, 'push');
            app.originalsignalButton.ButtonPushedFcn = createCallbackFcn(app, @originalsignalButtonPushed, true);
            app.originalsignalButton.Text = 'original signal';
            app.soundButton = uibutton(app.UIFigure, 'push');
            app.soundButton.ButtonPushedFcn = createCallbackFcn(app, @soundButtonPushed, true);
            app.soundButton.Text = 'sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speechDSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end